classdef finalapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        thenumberofvariablesEditFieldLabel  matlab.ui.control.Label
        thenumberofvariablesEditField  matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
        runButton                      matlab.ui.control.Button
        functionEditFieldLabel         matlab.ui.control.Label
        functionEditField              matlab.ui.control.EditField
        entertheoutputEditFieldLabel   matlab.ui.control.Label
        entertheoutputEditField        matlab.ui.control.EditField
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: runButton
        function runButtonPushed(app, event)
            n=app.thenumberofvariablesEditField.Value;
            N=2^n;
            Tab = zeros(N,n);
            for i=1:n
                y = [zeros(N/2^i,1); ones(N/2^i,1)];
                Tab(:,i) = repmat(y,2^(i-1),1);
            end
            a=app.entertheoutputEditField.Value;
            Tab(:,n+1)=str2double(strsplit(a,','));
            table=Tab;
            app.UITable.Data=table;
            app.UITable.ColumnName{n+1}='X';
            i=0;
            j=1;
            m=1;
            u=0;
            v=0;
            l=0;
            temp=1;
            s=char(zeros(1,N));
            for j=1:N
                if(table(j,n+1)==1)
                    u=u+1;
                    l=j;
                else
                    v=v+1;
                end
            end
            if(u<=v)
                for j=1:N
                    if(table(j,n+1)==1)
                        for k=1:n
                            if(table(j,m)==1)
                                s(temp)=char('A'+i);
                                temp=temp+1;
                            else
                                s(temp)=char('A'+i);
                                temp=temp+1;
                                s(temp)=char('′');
                                temp=temp+1;
                            end
                            i=i+1;
                            m=m+1;
                        end
                        if(j==l)
                            s(temp)=char('.');
                            temp=temp+1;
                        else
                            s(temp)=char('+');
                            temp=temp+1;
                        end
                    end
                    i=0;
                    m=1;
                end
            end
            if(v<u)
                for j=1:N
                    if(table(j,n+1)==0)
                        s(temp)=char('(');
                        temp=temp+1;
                        for k=1:n
                            if(table(j,m)==1)
                                s(temp)=char('A'+i);
                                temp=temp+1;
                                s(temp)=char('′');
                                temp=temp+1;
                            else
                                s(temp)=char('A'+i);
                                temp=temp+1;
                            end
                            if(k==n)
                                s(temp)=char(')');
                                temp=temp+1;
                            else
                                s(temp)=char('+');
                                temp=temp+1;
                                i=i+1;
                                m=m+1;
                            end
                        end
                    end
                    i=0;
                    m=1;
                end
            end
            if sum(Tab(:,n+1))== (2^n)
                app.functionEditField.Value=1;
            elseif sum(Tab(:,n+1))==0
                app.functionEditField.value=0;
            else
                app.functionEditField.Value=s;
            end
            xp=0:36;
            yp=-2:0.1:2;
            t=u;
            out=Tab(:,n+1);
            rows=2^n;
            cols=n;
            m=ones(rows,cols);
            
            for i = 1:cols
                j=1;
                while j<=rows
                    m(j,i)=0;
                    if mod(j,2^(cols-i))==0
                        j=j+2^(cols-i);
                    end
                    j=j+1;
                end
            end
            k=1;
            a=[];
            for i=1:2^n
                if out(i)==1
                    for j = 1:n
                        if m(i,j)==0
                            start=-17.5+(j-1)*5;
                        elseif m(i,j)==1
                            start=-20+(j-1)*5;
                        end
                        a(k,:)= linspace(start,2*n+2,3);
                        k=k+1;
                    end
                end


            end
            for i=1:n
                y1=15:-1:-t*32;
                x1=i*ones(1,length(y1));
                plot(app.UIAxes, 5*x1-25,y1,'r');
                axis([-30 80 -80 30])
                hold (app.UIAxes,'on');
                y2=6.6:-1:-t*32 ;
                x2=i*ones(1,length(y2))+0.5;
                plot(app.UIAxes,5*x2-25,y2,'m');
                
                y3=[10:-1:8] ;
                x3=i*ones(1,length(y3))+0.5;
                plot(app.UIAxes,5*x3-25,y3,'r');
                
                x4=i+0.25:0.05:i+0.25+0.5;
                y4=4*abs(x4-i-0.5)+7;
                plot(app.UIAxes,5*x4-25,y4,'b');
                r=0.2;
                th = 0:0.1:2*pi;
                xunit = r * cos(th);
                yunit = r * sin(th);
                plot(app.UIAxes,xunit+((5*x4(1)-25)+(5*x4(end)-25))/2, yunit+y4(1)-1.2,'b');
                yh=y3(end)*ones(1,length(x4));
                plot(app.UIAxes,5*x4-25,yh,'b');
                 x5=i:0.1:i+0.5;
                y5=y3(1)*ones(1,length(x5));
                plot(app.UIAxes,5*x5-25,y5,'r');
           end
            app.UIAxes.Visible=true;
            k=1;
            for i=0:t-1
                 y6=(0:-1:-8);
                x6=(2*n)*ones(1,length(y6))+2;
                plot(app.UIAxes,x6,y6-i*32,'b');
                plot(app.UIAxes,a(k,:),zeros(length(a(k,:)))+y6(1)-i*32,'m')
                k=k+1;
                %if n>2
                plot(app.UIAxes,a(k,:),zeros(length(a(k,:)))+y6(end)-i*32,'m')
                k=k+1;
                %end
                yc=-4:0.1:4;
                xc=sqrt(16-(yc).^2)+x6(1);
                plot(app.UIAxes,xc,yc+(y6(1)+y6(end))/2 - i*32,'b')
                plot(app.UIAxes,xc-4,zeros(1,length(xc))- i*32,'m')
                plot(app.UIAxes,xc-4,zeros(1,length(xc))- i*32-8,'m')
                for j=1:n-2
                    plot(app.UIAxes,x6+j*8,y6-i*32-j*4,'b');
                    plot(app.UIAxes,a(k,:),zeros(length(a(k,:)))+y6(end)-i*32-j*4,'m')
                    k=k+1;
                    plot(app.UIAxes,xc+j*8,yc+(y6(1)+y6(end))/2 - i*32-j*4,'b')
                    plot(app.UIAxes,xc+j*8,yc+(y6(1)+y6(end))/2 - i*32-j*4,'b')
                    plot(app.UIAxes,xc-4+j*8,zeros(1,length(xc))- i*32-j*4,'m')
                    plot(app.UIAxes,linspace(0,x6(1)+j*8,5),zeros(1,5)- i*32-8-j*4,'m')
                end
            end
            for i=0:t-2
                for k=1:t-1
                    xp1=-yp.^2;
                    xp2=-0.1*yp.^2-3.6;
                    plot(app.UIAxes,xp1+(n-2)*10+10+(i+1)*40,2*yp-4*i-8-(n-2)*4,'b')
                    plot(app.UIAxes,xp2+(n-2)*10+10+(i+1)*40,2*yp-4*i-8-(n-2)*4,'b')
                    plot(app.UIAxes,yp+(n-2)*10+44+40*(k-1),zeros(1,length(yp))- k*4-8 -(n-2)*4,'m')
                    plot(app.UIAxes,xp+(n-2)*10+10+40*(k-1),zeros(1,length(xp))- k*4-0-(n-2)*4,'m')
                    s=xp(1)+(n-2)*10+10;
                    f=yp(1)+(n-2)*10+44+40*(k-1);
                    xpp=s:f;
                    plot(app.UIAxes,xpp,zeros(1,length(xpp))- (k-1)*(32)-36-(n-2)*4,'m')
                    yh = (-(k-1)*(32)-36-(n-2)*4):(- k*4-8-(n-2)*4);
                    plot(app.UIAxes,zeros(1,length(yh))+xpp(end),yh,'m')
                end
            end
            plot(app.UIAxes,xp+(n-2)*10+10+40*(t-1),zeros(1,length(xp))- t*4-0-(n-2)*4,'m')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.thenumberofvariablesEditFieldLabel = uilabel(app.UIFigure);
            app.thenumberofvariablesEditFieldLabel.Text = 'the number of variables';
            app.thenumberofvariablesEditField = uieditfield(app.UIFigure, 'numeric');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x'; 'y'; 'z'; 'output'};
            app.UITable.RowName = {};
            app.runButton = uibutton(app.UIFigure, 'push');
            app.runButton.ButtonPushedFcn = createCallbackFcn(app, @runButtonPushed, true);
            app.runButton.Text = 'run';
            app.functionEditFieldLabel = uilabel(app.UIFigure);
            app.functionEditFieldLabel.Text = 'function';
            app.functionEditField = uieditfield(app.UIFigure, 'text');
            app.entertheoutputEditFieldLabel = uilabel(app.UIFigure);
            app.entertheoutputEditFieldLabel.Text = 'enter the output';
            app.entertheoutputEditField = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'circuit diagram')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = finalapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end